classdef CarWashSimulatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ErrorMessageLabel     matlab.ui.control.Label
        ProceedPanel          matlab.ui.container.Panel
        Label_2               matlab.ui.control.Label
        PROCEEDTOWASHLabel    matlab.ui.control.Label
        EnterCodePanel        matlab.ui.container.Panel
        EnteredCodeLabel      matlab.ui.control.Label
        CancelButton          matlab.ui.control.Button
        ClearButton           matlab.ui.control.Button
        Button_0              matlab.ui.control.Button
        Button_9              matlab.ui.control.Button
        Button_8              matlab.ui.control.Button
        Button_7              matlab.ui.control.Button
        Button_6              matlab.ui.control.Button
        Button_5              matlab.ui.control.Button
        Button_4              matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        Button_1              matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        Image                 matlab.ui.control.Image
        ShinyMotorsLabel      matlab.ui.control.Label
        SelectOperationLabel  matlab.ui.control.Label
        EnterCodeButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        chart % state chart object
        modelPath = addpath('Model\');
        modelName = 'CarWashSimulinkSimulator';
        ticketRepositoryAdapter;
        carWashAdapter;
        myTimer;
    end
    
    methods (Access = private)
        
        function showProceedPanel(app)
            app.ProceedPanel.Visible = 1;
            app.ProceedPanel.Enable = 'on';
            app.EnterCodePanel.Visible = 0;
            app.EnterCodePanel.Enable = 'off';
            app.SelectOperationLabel.Visible = 0;
            app.EnterCodeButton.Visible = 0;
            app.clearEnteredCodeLabel();
        end

        function showStartScreen(app)
            app.ProceedPanel.Visible = 0;
            app.ProceedPanel.Enable = 'off';
            app.EnterCodePanel.Visible = 0;
            app.EnterCodePanel.Enable = 'off';
            app.SelectOperationLabel.Visible = 1;
            app.EnterCodeButton.Visible = 1;
            app.ErrorMessageLabel.Visible = 0;
            app.ErrorMessageLabel.Text = '';
            app.clearEnteredCodeLabel();
        end

        function showErrorMessage(app, message)
            app.ErrorMessageLabel.Visible = 1;
            app.ErrorMessageLabel.Text = message;
            app.clearEnteredCodeLabel();
        end

        function clearEnteredCodeLabel(app)
            app.EnteredCodeLabel.Text = '';
            stop(app.myTimer);
        end

        function setUpTimerProperty(app, timeoutPeriod)
            app.myTimer = timer('Period',timeoutPeriod,...
                'StartDelay',timeoutPeriod, ...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.myTimer.TimerFcn = @(~,~) app.showStartScreen();
        end
        
        function result = canStartWash(app, ticket)
            % Validate ticket code
            validationResult = TicketValidator.validate(ticket);
            if(~validationResult.IsValid)
                result.isValid = false;
                result.message = validationResult.Message;
                return;
            end

            % Instantiate correct validator
            vldtor = CarWashValidatorFactory.create(ticket.getWashType());

            % Validate Car Wash State
            wpStatus = app.carWashAdapter.getWaterPumpStatus();
            soapLevel = app.carWashAdapter.getSoapLevel();
            waxLevel = app.carWashAdapter.getWaxLevel();
            ultraShineLevel = app.carWashAdapter.getUltraShineLevel();
            carWashStateIsValid = vldtor.canStartWash(wpStatus,...
                soapLevel,waxLevel,ultraShineLevel);
            if(~carWashStateIsValid)
                result.isValid = false;
                result.message = 'Car wash cannot start now';
                return;
            end

            result.isValid = true;
            result.message = '';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, config, carWashAdapter, ticketRepositoryAdapter)
            % CAR WASH ADAPTER
            if(isempty(carWashAdapter))
                app.carWashAdapter = CarWashAdapterFactory.create(config);
            else
                app.carWashAdapter = carWashAdapter;
            end
            % TICKET REPOSITORY ADAPTER
            if(isempty(ticketRepositoryAdapter))
                app.ticketRepositoryAdapter = TicketRepositoryAdapterFactory.create(config);
            else
                app.ticketRepositoryAdapter = ticketRepositoryAdapter;
            end
            % SCREEN TIMEOUT
            app.setUpTimerProperty(config.screenTimeoutPeriodSecs);            
        end

        % Button pushed function: EnterCodeButton
        function enterCodePushed(app, event)
            app.EnterCodePanel.Visible = 1;
            app.EnterCodePanel.Enable = 'on';
            start(app.myTimer);
        end

        % Button pushed function: Button_0, Button_1, Button_2, Button_3, 
        % Button_4, Button_5, Button_6, Button_7, Button_8, Button_9
        function keyPushed(app, event)
            if(size(app.EnteredCodeLabel.Text) < 6)
               newLabel = strcat(app.EnteredCodeLabel.Text,event.Source.Text);
               app.EnteredCodeLabel.Text = newLabel;
               if(strlength(app.EnteredCodeLabel.Text) == 6)
                   code = app.EnteredCodeLabel.Text;
                   ticket = app.ticketRepositoryAdapter.getTicket(code);
                   % Pre-validate wash can start
                   validationResult = app.canStartWash(ticket);
                   if(~validationResult.isValid)
                       app.showErrorMessage(validationResult.message);
                   else
                        app.showProceedPanel();
                        result = app.carWashAdapter.startWash(...
                            ticket.getWashType());
                        if(~isempty(result))
                            app.showStartScreen();
                        end
                   end
               end
            end
        end

        % Button pushed function: ClearButton
        function clearPushed(app, event)
            app.clearEnteredCodeLabel();
        end

        % Button pushed function: CancelButton
        function cancelPushed(app, event)
            app.showStartScreen();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnterCodeButton = uibutton(app.UIFigure, 'push');
            app.EnterCodeButton.ButtonPushedFcn = createCallbackFcn(app, @enterCodePushed, true);
            app.EnterCodeButton.Text = 'Enter Code';
            app.SelectOperationLabel = uilabel(app.UIFigure);
            app.SelectOperationLabel.Text = 'Select Operation';
            app.ShinyMotorsLabel = uilabel(app.UIFigure);
            app.ShinyMotorsLabel.Text = 'ShinyMotors';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'C:\Users\Daniel Hernandez\Documents\MATLAB\ShinyMotors\Assets\Images\logo.png';
            app.EnterCodePanel = uipanel(app.UIFigure);
            app.EnterCodePanel.Enable = 'off';
            app.EnterCodePanel.Title = 'Enter Code';
            app.EnterCodePanel.Visible = 'off';
            app.EnterCodePanel.Tag = 'EnterCodePanel';
            app.Button_3 = uibutton(app.EnterCodePanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_3.Text = '3';
            app.Button_1 = uibutton(app.EnterCodePanel, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.EnterCodePanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_2.Text = '2';
            app.Button_4 = uibutton(app.EnterCodePanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.EnterCodePanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.EnterCodePanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.EnterCodePanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.EnterCodePanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.EnterCodePanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_9.Text = '9';
            app.Button_0 = uibutton(app.EnterCodePanel, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_0.Text = '0';
            app.ClearButton = uibutton(app.EnterCodePanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearPushed, true);
            app.ClearButton.Text = 'CLEAR';
            app.CancelButton = uibutton(app.EnterCodePanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelPushed, true);
            app.CancelButton.Text = 'CANCEL';
            app.EnteredCodeLabel = uilabel(app.EnterCodePanel);
            app.EnteredCodeLabel.Tag = 'EnteredCodeLabel';
            app.EnteredCodeLabel.Text = '';
            app.ProceedPanel = uipanel(app.UIFigure);
            app.ProceedPanel.Enable = 'off';
            app.ProceedPanel.Visible = 'off';
            app.ProceedPanel.Tag = 'ProceedPanel';
            app.PROCEEDTOWASHLabel = uilabel(app.ProceedPanel);
            app.PROCEEDTOWASHLabel.Text = 'PROCEED TO WASH';
            app.Label_2 = uilabel(app.ProceedPanel);
            app.Label_2.Text = '<';
            app.ErrorMessageLabel = uilabel(app.UIFigure);
            app.ErrorMessageLabel.Visible = 'off';
            app.ErrorMessageLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CarWashSimulatorApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end